classdef pz_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        UpdateButton  matlab.ui.control.Button
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes_2      matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        steps % Description
        start_freq
        end_freq
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % STARTUP CALLBACK!
            app.steps = 80;
            app.start_freq = 0;
            app.end_freq = 7;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % My button pushed CALLBACK!
            
            
            %clc; clear all; format compact; close all
            
            poles = [-71 + 71j, -71 + -71j];
            
            zeroes = [];
            z = poly(zeroes)
            p = poly(poles);
            Hs = tf(z,p)
            %x = bodeoptions;
            %x.XLim = [0.01 100]
            %figure(1)
            fspace = logspace(app.start_freq,app.end_freq,app.steps)
            [mag,phase,wout] = bode(Hs,fspace);
            %software  opengl
            mag_plot = loglog(app.UIAxes,fspace,squeeze(mag));
            mag_plot(1).LineWidth = 3;

            phase_plot = loglog(app.UIAxes_2,fspace,squeeze(phase));
            phase_plot(1).LineWidth = 3;
            %grid on
            % no more
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mag')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Phase')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pz_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end